classdef double_elevator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        CabinAPanel                     matlab.ui.container.Panel
        goto1A                          matlab.ui.control.Button
        goto2A                          matlab.ui.control.Button
        goto4A                          matlab.ui.control.Button
        goto5A                          matlab.ui.control.Button
        closeDoorA                      matlab.ui.control.Button
        goto3A                          matlab.ui.control.Button
        openDoorA                       matlab.ui.control.Button
        emergencyA                      matlab.ui.control.Button
        goto6A                          matlab.ui.control.Button
        Floor5Panel                     matlab.ui.container.Panel
        Down5Button                     matlab.ui.control.Button
        LampFloor5                      matlab.ui.control.Lamp
        Up5Button                       matlab.ui.control.Button
        Floor4Panel                     matlab.ui.container.Panel
        Down4Button                     matlab.ui.control.Button
        Up4Button                       matlab.ui.control.Button
        LampFloor4                      matlab.ui.control.Lamp
        Floor2Panel                     matlab.ui.container.Panel
        Down2Button                     matlab.ui.control.Button
        Up2Button                       matlab.ui.control.Button
        LampFloor2                      matlab.ui.control.Lamp
        Floor1Panel                     matlab.ui.container.Panel
        Up1Button                       matlab.ui.control.Button
        LampFloor1                      matlab.ui.control.Lamp
        Floor3Panel                     matlab.ui.container.Panel
        Down3Button                     matlab.ui.control.Button
        Up3Button                       matlab.ui.control.Button
        LampFloor3                      matlab.ui.control.Lamp
        elevatorIndicatorPanel          matlab.ui.container.Panel
        DoorstateEditFieldLabel         matlab.ui.control.Label
        DoorStateEditField              matlab.ui.control.EditField
        ElevatorstateEditFieldLabel     matlab.ui.control.Label
        ElevatorStateEditField          matlab.ui.control.EditField
        ListcommandEditFieldLabel       matlab.ui.control.Label
        ListCommandEditField            matlab.ui.control.EditField
        CurrentdirectionEditFieldLabel  matlab.ui.control.Label
        CurrentDirectionEditField       matlab.ui.control.EditField
        CabinBPanel                     matlab.ui.container.Panel
        goto1B                          matlab.ui.control.Button
        goto2B                          matlab.ui.control.Button
        goto4B                          matlab.ui.control.Button
        goto5B                          matlab.ui.control.Button
        closeDoorB                      matlab.ui.control.Button
        goto3B                          matlab.ui.control.Button
        openDoorB                       matlab.ui.control.Button
        emergencyB                      matlab.ui.control.Button
        goto6B                          matlab.ui.control.Button
        Floor6Panel                     matlab.ui.container.Panel
        Down6Button                     matlab.ui.control.Button
        LampFloor6                      matlab.ui.control.Lamp
        CabinIndicatorPanel             matlab.ui.container.Panel
        cabinALampLabel                 matlab.ui.control.Label
        cabinAIsMovingLamp              matlab.ui.control.Lamp
        cabinBLampLabel                 matlab.ui.control.Label
        cabinBIsMovingLamp              matlab.ui.control.Lamp
    end

    
    properties (Access = public)
        % The left (A) right (B) elevator state, There are five states: idle, down, up, door, emergency
        cabinAIsMoving
        cabinBIsMoving
        
        % The direction of each elevator: true is moving up and vice versa
        cabinAGoingUp % True and false condition
        cabinBGoingUp % True and false condition
        
        % Left door and right door
        doorAIsOpening = false
        doorBIsOpening = false
        
        doorAState = 'canOpen' % canClose canKeepOpening
        doorBState = 'canOpen' % canClose canKeepOpening
                
        dispTimer % Use this timer to display the evevator
        data % A property to store all data

        % State for the elevator: idle down up and door
        % Idle is the normal case when the system is on idle state from which it can
        % go to door open or going up/down. from up/down need to go through idle state.
        
        % The current state of the right/leftelevator
        bothElevatorState % will be string, i.e., left: idle, right: down
        leftCurrentFloor % Figure out which floor the left elevator is
        rightCurrentFloor % Figure out which floor the right elevator is
        queue % Store the current queue command, contain "floorCommand" and corresponding "direction"
        
        % Two condition of the max min floor, current implementation is 5 floors
        maxFloor = 5;
        minFloor = 1;
        
        % Cabin condition
        cabinAPosition
        cabinBPosition
        
        currentCabinAPosition
        currentCabinBPosition
        
        nextLowerCabinAPosition
        nextUpperCabinAPosition
        nextLowerCabinBPosition
        nextUpperCabinBPosition
        
        % Some constant for visualization
        velXdoor = [0 0 1 1 0 -1 -1 0 0 -1;0 0 0 0 0 0 0 0 0 0]';
        velYdoor = 5*[0 0 0 0 0 0 0 0 0 0;1 1 1 1 1, 1 1 1 1 1]';
        
        backGround = cell(7+3,1) % Use fill function to generate background
        door = struct('left_doorA',[],'right_doorA',[],'left_doorB',[],'right_doorB',[]);

        dt = 1;
    end
    
    methods (Access = public)
        

    end
    
    methods (Access = private)
        
        function app = sortQueue(app)
            % The function to sort the queue based on the new command floor
            % app.queue contains two parameters: floor and direction
            if ~isempty(app.queue.floor)
                % Check the first direction
                if app.queue.floor(1) == 1 % Meaning the command is too going up
            
                end
            end
        end
                
        function app = moveElevator(app,~,~)
            app.queue = [];
            if isempty(app.queue)
                app.cabinAIsMovingLamp.Color = 'r';
                app.cabinBIsMovingLamp.Color = 'r';
                app.ElevatorStateEditField.Value = 'idle';
            end
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Delete the previous timer
            delete(timerfind)
            
            app.UIFigure.Position = [100 100 1200 800];
            
            % Create a new timer for new run
            app.dispTimer = timer('Period',0.03,...
                'ExecutionMode', 'fixedRate', ...
                'TasksToExecute', Inf);
            app.dispTimer.TimerFcn = {@app.moveElevator};
            
            % Generate random initial condition of two cabin
            cabinAInit = randi(6)-1;
            cabinBInit = randi(6)-1;
            
            app.door.left_doorA = fill(app.UIAxes,[2 25.5 25.5 2],[10 10 100 100]+100*cabinAInit,'g');
            hold(app.UIAxes)
            app.door.right_doorA = fill(app.UIAxes,[25.5 49 49 25.5],[10 10 100 100]+100*cabinAInit,'g');
            
            app.door.left_doorB = fill(app.UIAxes,[2 25.5 25.5 2]+49,[10 10 100 100]+100*cabinBInit,'g');
            app.door.right_doorB = fill(app.UIAxes,[25.5 49 49 25.5]+49,[10 10 100 100]+100*cabinBInit,'g');
            
            app.backGround{1} = fill(app.UIAxes,[0 100 100 0 0],[0 0 10 10 0],[120, 87, 85]/255);
            app.backGround{2} = fill(app.UIAxes,[0 100 100 0 0],[0 0 10 10 0]+100,[120, 87, 85]/255);
            app.backGround{3} = fill(app.UIAxes,[0 100 100 0 0],[0 0 10 10 0]+200,[120, 87, 85]/255);
            app.backGround{4} = fill(app.UIAxes,[0 100 100 0 0],[0 0 10 10 0]+300,[120, 87, 85]/255);
            app.backGround{5} = fill(app.UIAxes,[0 100 100 0 0],[0 0 10 10 0]+400,[120, 87, 85]/255);
            app.backGround{6} = fill(app.UIAxes,[0 100 100 0 0],[0 0 10 10 0]+500,[120, 87, 85]/255);
            app.backGround{7} = fill(app.UIAxes,[0 100 100 0 0],[0 0 10 10 0]+600,[120, 87, 85]/255);
            
            app.backGround{8} = fill(app.UIAxes,[49 51 51 49],[0 0 610 610],[120, 87, 85]/255);
            app.backGround{9} = fill(app.UIAxes,[0 2 2 0],[0 0 610 610],[120, 87, 85]/255);
            app.backGround{10} = fill(app.UIAxes,[98 100 100 98],[0 0 610 610],[120, 87, 85]/255);
            
            app.UIAxes.XLim = [-10 110];
            app.UIAxes.YLim = [-10 660];
            
            start(app.dispTimer)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Double elevator')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.CabinAPanel = uipanel(app.UIFigure);
            app.CabinAPanel.Title = 'Cabin A';
            app.goto1A = uibutton(app.CabinAPanel, 'push');
            app.goto1A.Text = '1';
            app.goto2A = uibutton(app.CabinAPanel, 'push');
            app.goto2A.Text = '2';
            app.goto4A = uibutton(app.CabinAPanel, 'push');
            app.goto4A.Text = '4';
            app.goto5A = uibutton(app.CabinAPanel, 'push');
            app.goto5A.Text = '5';
            app.closeDoorA = uibutton(app.CabinAPanel, 'push');
            app.closeDoorA.Text = 'Close door';
            app.goto3A = uibutton(app.CabinAPanel, 'push');
            app.goto3A.Text = '3';
            app.openDoorA = uibutton(app.CabinAPanel, 'push');
            app.openDoorA.Text = 'Open door';
            app.emergencyA = uibutton(app.CabinAPanel, 'push');
            app.emergencyA.Text = 'Emergency';
            app.goto6A = uibutton(app.CabinAPanel, 'push');
            app.goto6A.Text = '6';
            app.Floor5Panel = uipanel(app.UIFigure);
            app.Floor5Panel.Title = 'Floor 5';
            app.Down5Button = uibutton(app.Floor5Panel, 'push');
            app.Down5Button.Text = 'Down5';
            app.LampFloor5 = uilamp(app.Floor5Panel);
            app.Up5Button = uibutton(app.Floor5Panel, 'push');
            app.Up5Button.Text = 'Up5';
            app.Floor4Panel = uipanel(app.UIFigure);
            app.Floor4Panel.Title = 'Floor 4';
            app.Down4Button = uibutton(app.Floor4Panel, 'push');
            app.Down4Button.Text = 'Down4';
            app.Up4Button = uibutton(app.Floor4Panel, 'push');
            app.Up4Button.Text = 'Up4';
            app.LampFloor4 = uilamp(app.Floor4Panel);
            app.Floor2Panel = uipanel(app.UIFigure);
            app.Floor2Panel.Title = 'Floor 2';
            app.Down2Button = uibutton(app.Floor2Panel, 'push');
            app.Down2Button.Text = 'Down2';
            app.Up2Button = uibutton(app.Floor2Panel, 'push');
            app.Up2Button.Text = 'Up2';
            app.LampFloor2 = uilamp(app.Floor2Panel);
            app.Floor1Panel = uipanel(app.UIFigure);
            app.Floor1Panel.Title = 'Floor 1';
            app.Up1Button = uibutton(app.Floor1Panel, 'push');
            app.Up1Button.Text = 'Up1';
            app.LampFloor1 = uilamp(app.Floor1Panel);
            app.Floor3Panel = uipanel(app.UIFigure);
            app.Floor3Panel.Title = 'Floor 3';
            app.Down3Button = uibutton(app.Floor3Panel, 'push');
            app.Down3Button.Text = 'Down3';
            app.Up3Button = uibutton(app.Floor3Panel, 'push');
            app.Up3Button.Text = 'Up3';
            app.LampFloor3 = uilamp(app.Floor3Panel);
            app.elevatorIndicatorPanel = uipanel(app.UIFigure);
            app.elevatorIndicatorPanel.Title = 'elevator Indicator';
            app.DoorstateEditFieldLabel = uilabel(app.elevatorIndicatorPanel);
            app.DoorstateEditFieldLabel.Text = 'Door state';
            app.DoorStateEditField = uieditfield(app.elevatorIndicatorPanel, 'text');
            app.ElevatorstateEditFieldLabel = uilabel(app.elevatorIndicatorPanel);
            app.ElevatorstateEditFieldLabel.Text = 'Elevator state';
            app.ElevatorStateEditField = uieditfield(app.elevatorIndicatorPanel, 'text');
            app.ListcommandEditFieldLabel = uilabel(app.elevatorIndicatorPanel);
            app.ListcommandEditFieldLabel.Text = 'List command';
            app.ListCommandEditField = uieditfield(app.elevatorIndicatorPanel, 'text');
            app.CurrentdirectionEditFieldLabel = uilabel(app.elevatorIndicatorPanel);
            app.CurrentdirectionEditFieldLabel.Text = 'Current direction';
            app.CurrentDirectionEditField = uieditfield(app.elevatorIndicatorPanel, 'text');
            app.CabinBPanel = uipanel(app.UIFigure);
            app.CabinBPanel.Title = 'Cabin B';
            app.goto1B = uibutton(app.CabinBPanel, 'push');
            app.goto1B.Text = '1';
            app.goto2B = uibutton(app.CabinBPanel, 'push');
            app.goto2B.Text = '2';
            app.goto4B = uibutton(app.CabinBPanel, 'push');
            app.goto4B.Text = '4';
            app.goto5B = uibutton(app.CabinBPanel, 'push');
            app.goto5B.Text = '5';
            app.closeDoorB = uibutton(app.CabinBPanel, 'push');
            app.closeDoorB.Text = 'Close door';
            app.goto3B = uibutton(app.CabinBPanel, 'push');
            app.goto3B.Text = '3';
            app.openDoorB = uibutton(app.CabinBPanel, 'push');
            app.openDoorB.Text = 'Open door';
            app.emergencyB = uibutton(app.CabinBPanel, 'push');
            app.emergencyB.Text = 'Emergency';
            app.goto6B = uibutton(app.CabinBPanel, 'push');
            app.goto6B.Text = '6';
            app.Floor6Panel = uipanel(app.UIFigure);
            app.Floor6Panel.Title = 'Floor 6';
            app.Down6Button = uibutton(app.Floor6Panel, 'push');
            app.Down6Button.Text = 'Down6';
            app.LampFloor6 = uilamp(app.Floor6Panel);
            app.CabinIndicatorPanel = uipanel(app.UIFigure);
            app.CabinIndicatorPanel.Title = 'Cabin Indicator';
            app.cabinALampLabel = uilabel(app.CabinIndicatorPanel);
            app.cabinALampLabel.Text = 'cabin A';
            app.cabinAIsMovingLamp = uilamp(app.CabinIndicatorPanel);
            app.cabinBLampLabel = uilabel(app.CabinIndicatorPanel);
            app.cabinBLampLabel.Text = 'cabin B';
            app.cabinBIsMovingLamp = uilamp(app.CabinIndicatorPanel);
        end
    end

    methods (Access = public)

        % Construct app
        function app = double_elevator

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end